classdef drawtolife < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        Panel                     matlab.ui.container.Panel
        ChooseImageDropDownLabel  matlab.ui.control.Label
        ChooseImageDropDown       matlab.ui.control.DropDown
        DrawButton                matlab.ui.control.Button
        UploadImageButton         matlab.ui.control.Button
        EditField                 matlab.ui.control.EditField
    end

    
    properties (Access = public)
        im_file = "images/circles.jpg"; %as default
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                p = [p,f];
                app.im_file = p;
                app.EditField.Value = p;
            end
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            dtlDraw3D(app);
            msgfig = msgbox('Drawing complete!','Success','modal');
            uiwait(msgfig)
        end

        % Value changed function: ChooseImageDropDown
        function ChooseImageDropDownValueChanged(app, event)
            app.im_file = app.ChooseImageDropDown.Value; 
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X (in.)')
            ylabel(app.UIAxes, 'Y (in.)')
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ChooseImageDropDownLabel = uilabel(app.Panel);
            app.ChooseImageDropDownLabel.Text = 'Choose Image';
            app.ChooseImageDropDown = uidropdown(app.Panel);
            app.ChooseImageDropDown.Items = {'Circles', 'Owl', 'Dino', 'Dr. O''Malley'};
            app.ChooseImageDropDown.ItemsData = {'images\circles.jpg', 'images\owl2.jpg', 'images\dino.jpg', 'images\omalley.jpg'};
            app.ChooseImageDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseImageDropDownValueChanged, true);
            app.ChooseImageDropDown.Value = 'images\circles.jpg';
            app.DrawButton = uibutton(app.Panel, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw!';
            app.UploadImageButton = uibutton(app.Panel, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.EditField = uieditfield(app.Panel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drawtolife

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end